classdef FuerzaUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image5                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        PrecisinEditField        matlab.ui.control.NumericEditField
        PrecisinEditFieldLabel   matlab.ui.control.Label
        mu_0EditField            matlab.ui.control.EditField
        mu_0EditFieldLabel       matlab.ui.control.Label
        F3_mediaEditField        matlab.ui.control.NumericEditField
        FEditField_2Label_3      matlab.ui.control.Label
        F3_maxEditField          matlab.ui.control.NumericEditField
        FEditField_2Label_2      matlab.ui.control.Label
        B3_medioEditField        matlab.ui.control.NumericEditField
        B_medioEditFieldLabel_2  matlab.ui.control.Label
        B3_maxEditField          matlab.ui.control.NumericEditField
        B_maxEditFieldLabel_2    matlab.ui.control.Label
        Caso3Label               matlab.ui.control.Label
        F2EditField              matlab.ui.control.NumericEditField
        FEditField_2Label        matlab.ui.control.Label
        B2_medioEditField        matlab.ui.control.NumericEditField
        B_medioEditFieldLabel    matlab.ui.control.Label
        B2_maxEditField          matlab.ui.control.NumericEditField
        B_maxEditFieldLabel      matlab.ui.control.Label
        Caso2Label               matlab.ui.control.Label
        F1EditField              matlab.ui.control.NumericEditField
        FEditFieldLabel          matlab.ui.control.Label
        Caso1Label               matlab.ui.control.Label
        B1EditField              matlab.ui.control.NumericEditField
        BEditFieldLabel          matlab.ui.control.Label
        ResultadosLabel          matlab.ui.control.Label
        i_dcEditField            matlab.ui.control.NumericEditField
        i_dcEditFieldLabel       matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        ZonaBvarEditField        matlab.ui.control.NumericEditField
        ZonaBvarLabel            matlab.ui.control.Label
        h_cEditField             matlab.ui.control.NumericEditField
        h_cEditFieldLabel        matlab.ui.control.Label
        r_cextEditField          matlab.ui.control.NumericEditField
        r_cextEditFieldLabel     matlab.ui.control.Label
        r_bEditField             matlab.ui.control.NumericEditField
        r_bEditFieldLabel        matlab.ui.control.Label
        L_bEditField             matlab.ui.control.NumericEditField
        L_bEditFieldLabel        matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        GeometraLabel            matlab.ui.control.Label
        CalcularButton           matlab.ui.control.Button
        chiEditField             matlab.ui.control.NumericEditField
        chiEditFieldLabel        matlab.ui.control.Label
        mu_rEditField            matlab.ui.control.NumericEditField
        mu_rEditFieldLabel       matlab.ui.control.Label
        VariablesfsicasLabel     matlab.ui.control.Label
        F3Axis                   matlab.ui.control.UIAxes
        B3Axis                   matlab.ui.control.UIAxes
        F2Axis                   matlab.ui.control.UIAxes
        B2Axis                   matlab.ui.control.UIAxes
        F1Axis                   matlab.ui.control.UIAxes
        B1Axis                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [B] = InductionCalculator(app, numPoints, decayZone, B_center)

            decayArray = zeros(1, numPoints);
            truePoints = numPoints * decayZone/100;
            for i = 1:numPoints
                if i < numPoints/2 && i < truePoints
                    decayArray(i) = 1;
                elseif i > numPoints/2 && i > numPoints - truePoints
                    decayArray(i) = 1;
                end
            end
        
            varB = linspace((decayZone/100)*B_center, B_center, truePoints);
            B = zeros(1, numPoints);
            for i = 1:numPoints
                if decayArray(i) == 0
                    B(i) = B_center;
                elseif i < numPoints/2 && decayArray(i) == 1
                    B(i) = varB(i);
                elseif i > numPoints/2 && decayArray(i) == 1  && i ~= numPoints
                    pos = abs(numPoints - i);
                    B(i) = varB(pos);
                elseif i == numPoints
                    B(i) = varB(1);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularTodoFunc(app, event)
            %Constants:
            mu_r = app.mu_rEditField.Value;
            mu_0 = 4 * pi * 10^-7;
            mu = mu_r * mu_0;
            chi = app.chiEditField.Value;
            
            %Variables:
            numPoints = app.PrecisinEditField.Value;
            L_bar = app.L_bEditField.Value; %m
            r_bar = app.r_bEditField.Value; %m
            h_c = app.h_cEditField.Value; %m
            x = linspace(0, h_c, numPoints);
            decayZone = app.ZonaBvarEditField.Value;
            delta = decayZone/100 * h_c;%m
            r_cext = app.r_cextEditField.Value; %m
            i_dc = app.i_dcEditField.Value; %A
            N = app.NEditField.Value; %turns

            xlim(app.F1Axis, [min(x), max(x)]);
            xlim(app.B1Axis, [min(x), max(x)]);
            xlim(app.F2Axis, [min(x), max(x)]);
            xlim(app.B2Axis, [min(x), max(x)]);
            xlim(app.F3Axis, [min(x), max(x)]);
            xlim(app.B3Axis, [min(x), max(x)]);
            
            %Cálculos:
            V_bar = pi * r_bar^2 * L_bar; %m^3
            H_center = N * i_dc / h_c; %A-v/m
            B_center = mu * H_center; %T

            %Caso 1:
            F1 = chi * V_bar * B_center^2;
            app.B1EditField.Value = B_center;
            app.F1EditField.Value = F1;

            B_centerVec = B_center * ones(1, numPoints);
            F1Vec = F1 * ones(1, numPoints);
            plot(app.B1Axis, x, B_centerVec);
            plot(app.F1Axis, x, F1Vec);

            %Caso 2:
            B2 = app.InductionCalculator(numPoints, decayZone, B_center);
            dB_dx2 = gradient(B2, x);
            dB_dxMean2 = mean(dB_dx2);
            F2 = (chi * V_bar * B_center) * dB_dxMean2;
            app.B2_maxEditField.Value = max(B2);
            app.B2_medioEditField.Value = mean(B2);
            app.F2EditField.Value = F2;

            F2Vec = F2 * ones(1, numPoints);
            plot(app.B2Axis, x, B2);
            plot(app.F2Axis, x, F2Vec);

            %Caso 3:
            B3 = app.InductionCalculator(numPoints, decayZone, B_center);
            dB_dx3 = gradient(B3, x);
            dB_dxMean3 = mean(dB_dx3);
            F3 = zeros(1, numPoints);
            for i = 1:numPoints
                F3(i) = (chi * V_bar * B3(i)) * dB_dxMean3;
            end
            app.B3_maxEditField.Value = max(B3);
            app.B3_medioEditField.Value = mean(B3);
            app.F3_maxEditField.Value = max(F3);
            app.F3_mediaEditField.Value = mean(F3);

            plot(app.B3Axis, x, B3);
            plot(app.F3Axis, x, F3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.B1Axis = uiaxes(app.UIFigure);
            title(app.B1Axis, 'B1(x)')
            xlabel(app.B1Axis, 'x [m]')
            ylabel(app.B1Axis, 'B [T]')
            zlabel(app.B1Axis, 'Z')
            app.F1Axis = uiaxes(app.UIFigure);
            title(app.F1Axis, 'F1(x)')
            xlabel(app.F1Axis, 'x [m]')
            ylabel(app.F1Axis, 'F [N]')
            zlabel(app.F1Axis, 'Z')
            app.B2Axis = uiaxes(app.UIFigure);
            title(app.B2Axis, 'B2(x)')
            xlabel(app.B2Axis, 'x [m]')
            ylabel(app.B2Axis, 'B [T]')
            zlabel(app.B2Axis, 'Z')
            app.F2Axis = uiaxes(app.UIFigure);
            title(app.F2Axis, 'Title')
            xlabel(app.F2Axis, 'x [m]')
            ylabel(app.F2Axis, 'F [N]')
            zlabel(app.F2Axis, 'Z')
            app.B3Axis = uiaxes(app.UIFigure);
            title(app.B3Axis, 'B3(x)')
            xlabel(app.B3Axis, 'x [m]')
            ylabel(app.B3Axis, 'B [T]')
            zlabel(app.B3Axis, 'Z')
            app.F3Axis = uiaxes(app.UIFigure);
            title(app.F3Axis, 'F3(x)')
            xlabel(app.F3Axis, 'x [m]')
            ylabel(app.F3Axis, 'F [N]')
            zlabel(app.F3Axis, 'Z')
            app.VariablesfsicasLabel = uilabel(app.UIFigure);
            app.VariablesfsicasLabel.Text = 'Variables físicas';
            app.mu_rEditFieldLabel = uilabel(app.UIFigure);
            app.mu_rEditFieldLabel.Text = 'mu_r';
            app.mu_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.mu_rEditField.Value = 3000;
            app.chiEditFieldLabel = uilabel(app.UIFigure);
            app.chiEditFieldLabel.Text = 'chi';
            app.chiEditField = uieditfield(app.UIFigure, 'numeric');
            app.chiEditField.Value = 2000;
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTodoFunc, true);
            app.CalcularButton.Text = 'Calcular';
            app.GeometraLabel = uilabel(app.UIFigure);
            app.GeometraLabel.Text = 'Geometría';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Geometry.png');
            app.L_bEditFieldLabel = uilabel(app.UIFigure);
            app.L_bEditFieldLabel.Text = 'L_b';
            app.L_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.L_bEditField.Value = 0.096;
            app.r_bEditFieldLabel = uilabel(app.UIFigure);
            app.r_bEditFieldLabel.Text = 'r_b';
            app.r_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.r_bEditField.Value = 0.003045;
            app.r_cextEditFieldLabel = uilabel(app.UIFigure);
            app.r_cextEditFieldLabel.Text = 'r_cext';
            app.r_cextEditField = uieditfield(app.UIFigure, 'numeric');
            app.r_cextEditField.Value = 0.01064;
            app.h_cEditFieldLabel = uilabel(app.UIFigure);
            app.h_cEditFieldLabel.Text = 'h_c';
            app.h_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.h_cEditField.Value = 0.05321;
            app.ZonaBvarLabel = uilabel(app.UIFigure);
            app.ZonaBvarLabel.Text = 'Zona B var. (δ)';
            app.ZonaBvarEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZonaBvarEditField.Value = 10;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 500;
            app.i_dcEditFieldLabel = uilabel(app.UIFigure);
            app.i_dcEditFieldLabel.Text = 'i_dc';
            app.i_dcEditField = uieditfield(app.UIFigure, 'numeric');
            app.i_dcEditField.Value = 3.5;
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.B1EditField = uieditfield(app.UIFigure, 'numeric');
            app.B1EditField.Editable = 'off';
            app.Caso1Label = uilabel(app.UIFigure);
            app.Caso1Label.Text = 'B uniforme';
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F';
            app.F1EditField = uieditfield(app.UIFigure, 'numeric');
            app.F1EditField.Editable = 'off';
            app.Caso2Label = uilabel(app.UIFigure);
            app.Caso2Label.Text = {'B variable en los extremos'; 'Momento dipolar constante'};
            app.B_maxEditFieldLabel = uilabel(app.UIFigure);
            app.B_maxEditFieldLabel.Text = 'B_max';
            app.B2_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.B2_maxEditField.Editable = 'off';
            app.B_medioEditFieldLabel = uilabel(app.UIFigure);
            app.B_medioEditFieldLabel.Text = 'B_medio';
            app.B2_medioEditField = uieditfield(app.UIFigure, 'numeric');
            app.B2_medioEditField.Editable = 'off';
            app.FEditField_2Label = uilabel(app.UIFigure);
            app.FEditField_2Label.Text = 'F';
            app.F2EditField = uieditfield(app.UIFigure, 'numeric');
            app.F2EditField.Editable = 'off';
            app.Caso3Label = uilabel(app.UIFigure);
            app.Caso3Label.Text = {'B variable en los extremos'; 'Momento dipolar variable'};
            app.B_maxEditFieldLabel_2 = uilabel(app.UIFigure);
            app.B_maxEditFieldLabel_2.Text = 'B_max';
            app.B3_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.B3_maxEditField.Editable = 'off';
            app.B_medioEditFieldLabel_2 = uilabel(app.UIFigure);
            app.B_medioEditFieldLabel_2.Text = 'B_medio';
            app.B3_medioEditField = uieditfield(app.UIFigure, 'numeric');
            app.B3_medioEditField.Editable = 'off';
            app.FEditField_2Label_2 = uilabel(app.UIFigure);
            app.FEditField_2Label_2.Text = 'F_max';
            app.F3_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.F3_maxEditField.Editable = 'off';
            app.FEditField_2Label_3 = uilabel(app.UIFigure);
            app.FEditField_2Label_3.Text = 'F_media';
            app.F3_mediaEditField = uieditfield(app.UIFigure, 'numeric');
            app.F3_mediaEditField.Editable = 'off';
            app.mu_0EditFieldLabel = uilabel(app.UIFigure);
            app.mu_0EditFieldLabel.Text = 'mu_0';
            app.mu_0EditField = uieditfield(app.UIFigure, 'text');
            app.mu_0EditField.Editable = 'off';
            app.mu_0EditField.Value = '4π*10^-7';
            app.PrecisinEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecisinEditField.Value = 3000;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'FormGenç.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Caso1Form.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Caso2Form.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Caso3Form.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuerzaUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end